classdef Map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LidarLabel                   matlab.ui.control.Label
        InflationResetButton         matlab.ui.control.Button
        Inflation                    matlab.ui.control.Button
        STOPICP                      matlab.ui.control.Button
        ICP                          matlab.ui.control.Button
        ROSShutdownButton            matlab.ui.control.Button
        ROSInitializationButton      matlab.ui.control.Button
        LoadCalculationResultButton  matlab.ui.control.Button
        StatusTextArea               matlab.ui.control.TextArea
        StatusLabel                  matlab.ui.control.Label
        LoadMapButton                matlab.ui.control.Button
        RunRobotLabel                matlab.ui.control.Label
        PathCalculationLabel         matlab.ui.control.Label
        MapInitializationLabel       matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        ConstructMapTab              matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        PathResultTab                matlab.ui.container.Tab
        UIAxes_pathresult            matlab.ui.control.UIAxes
        RealtimeTab                  matlab.ui.container.Tab
        PoseEditField                matlab.ui.control.NumericEditField
        PoseEditFieldLabel           matlab.ui.control.Label
        UIAxes_realtimeresult        matlab.ui.control.UIAxes
        TrajectoryTab                matlab.ui.container.Tab
        UIAxes_trajectory            matlab.ui.control.UIAxes
        EraseMapButton               matlab.ui.control.StateButton
        SetGridButtonGroup           matlab.ui.container.ButtonGroup
        EndPositionButton            matlab.ui.control.RadioButton
        OccupyButton                 matlab.ui.control.RadioButton
        StartPositionButton          matlab.ui.control.RadioButton
        GOButton                     matlab.ui.control.Button
        RRTCalculateButton           matlab.ui.control.Button
        ConstructMapButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        OccupiedPoints = zeros(0, 2); % To store occupied points
        StartPosition=zeros(1,2);
        EndPosition=zeros(0,2);
        StartPositionPlot_flag=0;
        %fine grid version
        OccupiedMap=binaryOccupancyMap(200,200,0.1);
        MapXidxLimit=[1,20];
        MapYidxLimit=[1,20];
        Occupiedidx=zeros(0,2);
        InflatedOccupiedidx=zeros(0,2);
        beforedInflatedOccupiedidx=zeros(0,2);
        pureInflatedOccupiedidx=zeros(0,2);
        AxesLock=0;
        WorkArea = [0 170 0 120]; % Added proprety to reduce grid size (maybe switch x and y if necessary)
    end
    
    methods (Access = private)
        function Inflatedidx=inflateGrididx(app) 
            temp_Inflatedidx=zeros(0,2);
            for i=1:size(app.Occupiedidx,1)
                
                Inflation_condition=~((app.Occupiedidx(i,1)<=app.MapXidxLimit(1))||(app.Occupiedidx(i,1)>=app.MapXidxLimit(2))||...
                    (app.Occupiedidx(i,2)<=app.MapYidxLimit(1))||(app.Occupiedidx(i,2)>=app.MapYidxLimit(2)));
                if Inflation_condition
                    
                    InflatedCoordinate=[app.Occupiedidx(i,1)+1,app.Occupiedidx(i,2);
                        app.Occupiedidx(i,1),   app.Occupiedidx(i,2)+1;
                        app.Occupiedidx(i,1)+1, app.Occupiedidx(i,2)+1;
                        app.Occupiedidx(i,1)-1, app.Occupiedidx(i,2);
                        app.Occupiedidx(i,1),   app.Occupiedidx(i,2)-1;
                        app.Occupiedidx(i,1)-1, app.Occupiedidx(i,2)-1;
                        app.Occupiedidx(i,1)+1, app.Occupiedidx(i,2)-1;
                        app.Occupiedidx(i,1)-1, app.Occupiedidx(i,2)+1;];
                    temp_Inflatedidx=[temp_Inflatedidx;InflatedCoordinate];
                end
            end
            [~,ia,~]=unique(temp_Inflatedidx,'rows');
            Inflatedidx=temp_Inflatedidx(ia,:);
        end
        function pureinflatedidx=ExtractUniqueidx(app,Inflatedidx,beforeInflated)
            temp_indexArray=ismember(Inflatedidx,beforeInflated,"rows");
            pureinflatedidx=Inflatedidx(~temp_indexArray,:);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Draw start position since robot starts from 0,0
            rectangle(app.UIAxes, 'Position', [app.StartPosition(1)-5, app.StartPosition(2)-5, 10, 10], 'FaceColor', 'blue');
            app.StartPositionPlot_flag=1;
            app.StartPositionButton.Value=0;
            app.StartPositionButton.Enable="off";
            % ===== Work-area boundary mask (VISIBLE + OCCUPANCY OBSTACLES) =====
            xmin = 0;  xmax = 170;   % <-- change these (cm)
            ymin = 0;  ymax = 120;   % <-- change these (cm)
            
            X0 = app.UIAxes.XLim(1); X1 = app.UIAxes.XLim(2);
            Y0 = app.UIAxes.YLim(1); Y1 = app.UIAxes.YLim(2);
            
            % 1) Draw visible black mask outside the work area
            hold(app.UIAxes,'on');
            
            % Left
            if xmin > X0
                rectangle(app.UIAxes,'Position',[X0, Y0, xmin-X0, Y1-Y0], ...
                    'FaceColor','black','EdgeColor','black');
            end
            % Right
            if xmax < X1
                rectangle(app.UIAxes,'Position',[xmax, Y0, X1-xmax, Y1-Y0], ...
                    'FaceColor','black','EdgeColor','black');
            end
            % Bottom
            if ymin > Y0
                rectangle(app.UIAxes,'Position',[X0, Y0, X1-X0, ymin-Y0], ...
                    'FaceColor','black','EdgeColor','black');
            end
            % Top
            if ymax < Y1
                rectangle(app.UIAxes,'Position',[X0, ymax, X1-X0, Y1-ymax], ...
                    'FaceColor','black','EdgeColor','black');
            end
            
            hold(app.UIAxes,'off');
            
            % 2) Mark outside region as occupied in the binaryOccupancyMap
            gs = app.OccupiedMap.GridSize;            % [rows cols]
            [I, J] = ndgrid(1:gs(1), 1:gs(2));
            allIdx = [I(:) J(:)];
            
            w = grid2world(app.OccupiedMap, allIdx);   % cell centers in cm
            
            outside = w(:,1) < xmin | w(:,1) > xmax | w(:,2) < ymin | w(:,2) > ymax;
            
            if any(outside)
                outsideWorld = w(outside,:);
                setOccupancy(app.OccupiedMap, outsideWorld, 1);
            end
            % ================================================================
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            %fined grid version
            if app.AxesLock==1
                return
            end
            clickedPoint = event.IntersectionPoint(1, 1:2);
            clickedX = round(clickedPoint(1)+5,-1);
            clickedY = round(clickedPoint(2)+5,-1);
            if app.OccupyButton.Value==1
                % Check if the clicked point is already occupied
                if ~checkOccupancy(app.OccupiedMap,[clickedX, clickedY])
                    ijlocal=world2grid(app.OccupiedMap,[clickedX, clickedY])
                    % Add the occupied point to the list
                    app.OccupiedPoints = [app.OccupiedPoints; [clickedX, clickedY]];
                    app.Occupiedidx=[app.Occupiedidx;[ijlocal(1) ijlocal(2)]];
                    app.beforedInflatedOccupiedidx=[app.beforedInflatedOccupiedidx;[ijlocal(1) ijlocal(2)]];
                    % Display occupied grid (size 1x1) at the clicked point
                    rectY=200-((ijlocal(1)-1)*10);
                    rectX=10*(ijlocal(2));
                    rectangle(app.UIAxes, 'Position', [rectX-10, rectY-10, 10, 10], 'FaceColor', 'black');
                    setOccupancy(app.OccupiedMap,app.OccupiedPoints,ones(size(app.OccupiedPoints,1),1))
                    %show(app.OccupiedMap)
                    
                else
                    disp("something wrong")
                end
            elseif app.EndPositionButton.Value==1
                app.EndPosition=[clickedX, clickedY];
                rectangle(app.UIAxes, 'Position', [clickedX-5, clickedY-5, 10, 10], 'FaceColor', 'red');
                app.EndPositionButton.Enable="off";
                app.EndPositionButton.Value=0;
                app.OccupyButton.Value=1;
            end
            % elseif app.StartPositionButton.Value==1
            %     app.StartPosition=[clickedX, clickedY];
            %     rectangle(app.UIAxes, 'Position', [clickedX-5, clickedY-5, 10, 10], 'FaceColor', 'blue');
            %     app.StartPositionButton.Enable="off";
            %     app.StartPositionButton.Value=0;
            %     app.OccupyButton.Value=1;
            

        end

        % Value changed function: EraseMapButton
        function EraseMapButtonValueChanged(app, event)
            value = app.EraseMapButton.Value;
            app.AxesLock=0;
            rectangles=findobj(app.UIAxes,'Type','rectangle');
            delete(rectangles);
            app.OccupiedPoints=zeros(0,2);
            app.EndPosition=zeros(1,2);
            app.StartPosition=zeros(1,2);
            %rough grid version
            % app.OccupiedMap=binaryOccupancyMap(200,200,0.05);
            %fine grid version
            %clear variable
            app.OccupiedMap=binaryOccupancyMap(200,200,0.1);
                         % ===== Work-area boundary mask (VISIBLE + OCCUPANCY OBSTACLES) =====
            xmin = 0;  xmax = 170;   % <-- change these (cm)
            ymin = 0;  ymax = 120;   % <-- change these (cm)
            
            X0 = app.UIAxes.XLim(1); X1 = app.UIAxes.XLim(2);
            Y0 = app.UIAxes.YLim(1); Y1 = app.UIAxes.YLim(2);
            
            % 1) Draw visible black mask outside the work area
            hold(app.UIAxes,'on');
            
            % Left
            if xmin > X0
                rectangle(app.UIAxes,'Position',[X0, Y0, xmin-X0, Y1-Y0], ...
                    'FaceColor','black','EdgeColor','black');
            end
            % Right
            if xmax < X1
                rectangle(app.UIAxes,'Position',[xmax, Y0, X1-xmax, Y1-Y0], ...
                    'FaceColor','black','EdgeColor','black');
            end
            % Bottom
            if ymin > Y0
                rectangle(app.UIAxes,'Position',[X0, Y0, X1-X0, ymin-Y0], ...
                    'FaceColor','black','EdgeColor','black');
            end
            % Top
            if ymax < Y1
                rectangle(app.UIAxes,'Position',[X0, ymax, X1-X0, Y1-ymax], ...
                    'FaceColor','black','EdgeColor','black');
            end
            
            hold(app.UIAxes,'off');
            
            % 2) Mark outside region as occupied in the binaryOccupancyMap
            gs = app.OccupiedMap.GridSize;            % [rows cols]
            [I, J] = ndgrid(1:gs(1), 1:gs(2));
            allIdx = [I(:) J(:)];
            
            w = grid2world(app.OccupiedMap, allIdx);   % cell centers in cm
            
            outside = w(:,1) < xmin | w(:,1) > xmax | w(:,2) < ymin | w(:,2) > ymax;
            
            if any(outside)
                outsideWorld = w(outside,:);
                setOccupancy(app.OccupiedMap, outsideWorld, 1);
            end
            % ================================================================
            app.EndPositionButton.Enable="on";
            app.StartPositionButton.Enable="off";
            app.StartPositionPlot_flag=0;
            app.Occupiedidx=zeros(0,2);
            app.InflatedOccupiedidx=zeros(0,2);
            app.beforedInflatedOccupiedidx=zeros(0,2);
            
        end

        % Button pushed function: ConstructMapButton
        function ConstructMapButtonPushed(app, event)
            %StartPosition=app.StartPosition;
            StartPosition=[0,0];
            EndPosition=app.EndPosition;
            OccupiedMap=app.OccupiedMap;
            OccupiedPoints=app.OccupiedPoints;
            Occupedidx=app.Occupiedidx;
            xlimit=app.UIAxes.XLim;
            ylimit=app.UIAxes.YLim;
            app.AxesLock=1;
        %    show(app.OccupiedMap)
            save("map2mat.mat","StartPosition","EndPosition","OccupiedMap","OccupiedPoints","xlimit","ylimit","Occupedidx");
        end

        % Button pushed function: RRTCalculateButton
        function RRTCalculateButtonPushed(app, event)
            %try
                app.StatusTextArea.Value="Calculating...";
                RRTstarDIY_fn(app);
                app.StatusTextArea.Value="Calculation finished!";
            %catch
                %app.StatusTextArea.Value="Calculation failed, try again!";
            %end
        end

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
            if ~exist("map2mat.mat")
                app.StatusTextArea.Value="The map needs to be constructed first!";
            else
                app.StatusTextArea.Value="Map Loading....";
                load("map2mat.mat");
                app.StartPosition=StartPosition;
                app.EndPosition=EndPosition;
                app.OccupiedMap=OccupiedMap;
                app.OccupiedPoints=OccupiedPoints;
                app.Occupiedidx=Occupedidx;
                rectangle(app.UIAxes, 'Position', [app.StartPosition(1)-5, app.StartPosition(2)-5, 10, 10], 'FaceColor', 'blue');
                rectangle(app.UIAxes, 'Position', [app.EndPosition(1)-5, app.EndPosition(2)-5, 10, 10], 'FaceColor', 'red');
                %Extract occupied points from occupied map
                % ijlocal=world2grid(app.OccupiedMap,app.OccupiedPoints)
                % rectY=200-((ijlocal(:,1)-1)*10);
                % rectX=10*(ijlocal(:,2));
                %Use occuped idex for rectangular 
                rectY=200-((app.Occupiedidx(:,1)-1)*10);
                rectX=10*(app.Occupiedidx(:,2));
                for i=1:size(app.OccupiedPoints,1)
                    
                    rectangle(app.UIAxes, 'Position', [rectX(i)-10, rectY(i)-10, 10, 10], 'FaceColor', 'black');
                   
                end
                %show(app.OccupiedMap)
                app.StatusTextArea.Value="";

            end
        end

        % Button pushed function: LoadCalculationResultButton
        function LoadCalculationResultButtonPushed(app, event)
            load('path_result.mat','-mat');
            % load('trajectory_result.mat','-mat');
            ax2=findobj(f2,'Type','axes');
            copyobj(allchild(ax2),app.UIAxes_pathresult);
            % ax3=findobj(f3,'Type','axes');
            % copyobj(allchild(ax3),app.UIAxes_trajectory);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            global position
            position=zeros([0,3]);
            app.StatusTextArea.Value="Preparing";
            
            app.UIAxes_trajectory.XLim=[-200 200];
            app.UIAxes_trajectory.YLim=[-200 200];


            GoRobot(app);
            app.StatusTextArea.Value="End";
        end

        % Button pushed function: ROSInitializationButton
        function ROSInitializationButtonPushed(app, event)
            rosinit()
        end

        % Button pushed function: ROSShutdownButton
        function ROSShutdownButtonPushed(app, event)
            rosshutdown();
            
        end

        % Button pushed function: ICP
        function ICPButtonPushed(app, event)
            global temp_count
            global stopflag
            global LidarCoord
            stopflag=1;
            tposNode=ros2node("/GUI"); 
            targetposSub=ros2subscriber(tposNode,"/position","geometry_msgs/Point",@positionCallback);
            lidarscanSub=ros2subscriber(tposNode,"/scan","sensor_msgs/LaserScan",@LaserCallback);
            app.StatusTextArea.Value="Running";


            pool = parpool('Processes'); % run the scanMatch function in background
            q = parallel.pool.PollableDataQueue;
            
            send(q,LidarCoord);
            f=parfeval(backgroundPool,@scanMatch,1,q,LidarCoord);
            count=0;
            while stopflag
                send(q,LidarCoord);
                pause(0.5);
                temp_count
                
            end


        end

        % Button pushed function: STOPICP
        function STOPICPButtonPushed(app, event)
            global stopflag
            delete(gcp('nocreate'));
            stopflag=0;
            app.StatusTextArea.Value="Stopped";
        end

        % Button pushed function: Inflation
        function InflationButtonPushed(app, event)
            if ~isempty(app.Occupiedidx)
                app.StatusTextArea.Value="Grid Inflating";
                app.InflatedOccupiedidx=inflateGrididx(app);
                %Find only inflation array to plot it in different color
                app.pureInflatedOccupiedidx=ExtractUniqueidx(app,app.InflatedOccupiedidx,app.beforedInflatedOccupiedidx);
                
                rectY=200-(( app.pureInflatedOccupiedidx(:,1)-1)*10);
                rectX=10*( app.pureInflatedOccupiedidx(:,2));
                for i=1:size(app.pureInflatedOccupiedidx,1)
                    rectangle(app.UIAxes, 'Position', [rectX(i)-10, rectY(i)-10, 10, 10], 'FaceColor', [0.8500 0.3250 0.0980]);
                end
                %Update Occupied points and Occuped index
                newIdx = inflateGrididx(app);
                app.OccupiedPoints = grid2world(app.OccupiedMap, newIdx);
                app.Occupiedidx = newIdx;
                %Update Occuped map
                setOccupancy(app.OccupiedMap,app.OccupiedPoints,ones(size(app.OccupiedPoints,1),1))
                %show(app.OccupiedMap)
                app.StatusTextArea.Value="Inflation Done";
            end
        end

        % Button pushed function: InflationResetButton
        function InflationResetButtonPushed(app, event)
            if (~isempty(app.Occupiedidx))& (~isempty(app.EndPosition))
                app.StatusTextArea.Value="Resetting";
                %Reset Occuped Points and Occupied index
                app.OccupiedMap=binaryOccupancyMap(200,200,0.1);
                 % ===== Work-area boundary mask (VISIBLE + OCCUPANCY OBSTACLES) =====
                xmin = 0;  xmax = 170;   % <-- change these (cm)
                ymin = 0;  ymax = 120;   % <-- change these (cm)
                
                X0 = app.UIAxes.XLim(1); X1 = app.UIAxes.XLim(2);
                Y0 = app.UIAxes.YLim(1); Y1 = app.UIAxes.YLim(2);
                
                % 1) Draw visible black mask outside the work area
                hold(app.UIAxes,'on');
                
                % Left
                if xmin > X0
                    rectangle(app.UIAxes,'Position',[X0, Y0, xmin-X0, Y1-Y0], ...
                        'FaceColor','black','EdgeColor','black');
                end
                % Right
                if xmax < X1
                    rectangle(app.UIAxes,'Position',[xmax, Y0, X1-xmax, Y1-Y0], ...
                        'FaceColor','black','EdgeColor','black');
                end
                % Bottom
                if ymin > Y0
                    rectangle(app.UIAxes,'Position',[X0, Y0, X1-X0, ymin-Y0], ...
                        'FaceColor','black','EdgeColor','black');
                end
                % Top
                if ymax < Y1
                    rectangle(app.UIAxes,'Position',[X0, ymax, X1-X0, Y1-ymax], ...
                        'FaceColor','black','EdgeColor','black');
                end
                
                hold(app.UIAxes,'off');
                
                % 2) Mark outside region as occupied in the binaryOccupancyMap
                gs = app.OccupiedMap.GridSize;            % [rows cols]
                [I, J] = ndgrid(1:gs(1), 1:gs(2));
                allIdx = [I(:) J(:)];
                
                w = grid2world(app.OccupiedMap, allIdx);   % cell centers in cm
                
                outside = w(:,1) < xmin | w(:,1) > xmax | w(:,2) < ymin | w(:,2) > ymax;
                
                if any(outside)
                    outsideWorld = w(outside,:);
                    setOccupancy(app.OccupiedMap, outsideWorld, 1);
                end
                % ================================================================
                app.OccupiedPoints=grid2world(app.OccupiedMap,app.beforedInflatedOccupiedidx);
                app.Occupiedidx=app.beforedInflatedOccupiedidx;
                %Update Occuped map
                setOccupancy(app.OccupiedMap,app.OccupiedPoints,ones(size(app.OccupiedPoints,1),1))
                %Redraw the axes
                app.AxesLock=0;
                rectangles=findobj(app.UIAxes,'Type','rectangle');
                delete(rectangles);
                rectangle(app.UIAxes, 'Position', [app.StartPosition(1)-5, app.StartPosition(2)-5, 10, 10], 'FaceColor', 'blue');
                rectangle(app.UIAxes, 'Position', [app.EndPosition(1)-5, app.EndPosition(2)-5, 10, 10], 'FaceColor', 'red');
                rectY=200-((app.Occupiedidx(:,1)-1)*10);
                rectX=10*(app.Occupiedidx(:,2));
                for i=1:size(app.OccupiedPoints,1)
                    
                    rectangle(app.UIAxes, 'Position', [rectX(i)-10, rectY(i)-10, 10, 10], 'FaceColor', 'black');
                   
                end
                app.StatusTextArea.Value="Resetting Done";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConstructMapButton = uibutton(app.UIFigure, 'push');
            app.ConstructMapButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructMapButtonPushed, true);
            app.ConstructMapButton.Text = 'Construct Map';
            app.RRTCalculateButton = uibutton(app.UIFigure, 'push');
            app.RRTCalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RRTCalculateButtonPushed, true);
            app.RRTCalculateButton.Text = 'RRT* Calculate';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.SetGridButtonGroup = uibuttongroup(app.UIFigure);
            app.SetGridButtonGroup.Title = 'Set Grid';
            app.StartPositionButton = uiradiobutton(app.SetGridButtonGroup);
            app.StartPositionButton.Text = 'Start Position';
            app.StartPositionButton.Value = true;
            app.OccupyButton = uiradiobutton(app.SetGridButtonGroup);
            app.OccupyButton.Text = 'Occupy';
            app.EndPositionButton = uiradiobutton(app.SetGridButtonGroup);
            app.EndPositionButton.Text = 'End Position';
            app.EraseMapButton = uibutton(app.UIFigure, 'state');
            app.EraseMapButton.ValueChangedFcn = createCallbackFcn(app, @EraseMapButtonValueChanged, true);
            app.EraseMapButton.Text = 'Erase Map';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConstructMapTab = uitab(app.TabGroup);
            app.ConstructMapTab.Title = 'ConstructMap';
            app.UIAxes = uiaxes(app.ConstructMapTab);
            title(app.UIAxes, 'Occupancy Map')
            xlabel(app.UIAxes, 'X (cm)')
            ylabel(app.UIAxes, 'Y (cm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 200];
            app.UIAxes.YLim = [0 200];
            app.UIAxes.XTick = [0 50 100 150 200];
            app.UIAxes.YTick = [0 50 100 150 200];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PathResultTab = uitab(app.TabGroup);
            app.PathResultTab.Title = 'PathResult';
            app.UIAxes_pathresult = uiaxes(app.PathResultTab);
            title(app.UIAxes_pathresult, 'Path Result')
            xlabel(app.UIAxes_pathresult, 'X (cm)')
            ylabel(app.UIAxes_pathresult, 'Y (cm)')
            zlabel(app.UIAxes_pathresult, 'Z')
            app.UIAxes_pathresult.XLim = [0 200];
            app.UIAxes_pathresult.YLim = [0 200];
            app.RealtimeTab = uitab(app.TabGroup);
            app.RealtimeTab.Title = 'Realtime';
            app.UIAxes_realtimeresult = uiaxes(app.RealtimeTab);
            title(app.UIAxes_realtimeresult, 'Real-time Result ')
            xlabel(app.UIAxes_realtimeresult, 'X (cm)')
            ylabel(app.UIAxes_realtimeresult, 'Y (cm)')
            zlabel(app.UIAxes_realtimeresult, 'Z')
            app.UIAxes_realtimeresult.XLim = [0 200];
            app.UIAxes_realtimeresult.YLim = [0 200];
            app.PoseEditFieldLabel = uilabel(app.RealtimeTab);
            app.PoseEditFieldLabel.Text = 'Pose:';
            app.PoseEditField = uieditfield(app.RealtimeTab, 'numeric');
            app.TrajectoryTab = uitab(app.TabGroup);
            app.TrajectoryTab.Title = 'Trajectory';
            app.UIAxes_trajectory = uiaxes(app.TrajectoryTab);
            title(app.UIAxes_trajectory, 'Target Trajectory')
            xlabel(app.UIAxes_trajectory, 'X (cm)')
            ylabel(app.UIAxes_trajectory, 'Y (cm)')
            zlabel(app.UIAxes_trajectory, 'Z')
            app.UIAxes_trajectory.XLim = [0 200];
            app.UIAxes_trajectory.YLim = [0 200];
            app.MapInitializationLabel = uilabel(app.UIFigure);
            app.MapInitializationLabel.Text = 'Map Initialization';
            app.PathCalculationLabel = uilabel(app.UIFigure);
            app.PathCalculationLabel.Text = 'Path Calculation';
            app.RunRobotLabel = uilabel(app.UIFigure);
            app.RunRobotLabel.Text = 'Run Robot';
            app.LoadMapButton = uibutton(app.UIFigure, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Text = 'Load Map';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status: ';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.LoadCalculationResultButton = uibutton(app.UIFigure, 'push');
            app.LoadCalculationResultButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalculationResultButtonPushed, true);
            app.LoadCalculationResultButton.Text = 'Load Calculation Result';
            app.ROSInitializationButton = uibutton(app.UIFigure, 'push');
            app.ROSInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @ROSInitializationButtonPushed, true);
            app.ROSInitializationButton.Text = 'ROS Initialization';
            app.ROSShutdownButton = uibutton(app.UIFigure, 'push');
            app.ROSShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ROSShutdownButtonPushed, true);
            app.ROSShutdownButton.Text = 'ROS Shutdown';
            app.ICP = uibutton(app.UIFigure, 'push');
            app.ICP.ButtonPushedFcn = createCallbackFcn(app, @ICPButtonPushed, true);
            app.ICP.Text = 'ICP GOGO';
            app.STOPICP = uibutton(app.UIFigure, 'push');
            app.STOPICP.ButtonPushedFcn = createCallbackFcn(app, @STOPICPButtonPushed, true);
            app.STOPICP.Text = 'STOPICP';
            app.Inflation = uibutton(app.UIFigure, 'push');
            app.Inflation.ButtonPushedFcn = createCallbackFcn(app, @InflationButtonPushed, true);
            app.Inflation.Text = 'Grid Inflation';
            app.InflationResetButton = uibutton(app.UIFigure, 'push');
            app.InflationResetButton.ButtonPushedFcn = createCallbackFcn(app, @InflationResetButtonPushed, true);
            app.InflationResetButton.Text = 'Inflation Reset';
            app.LidarLabel = uilabel(app.UIFigure);
            app.LidarLabel.Text = 'Lidar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end